classdef Clueless < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GameSetupPanel           matlab.ui.container.Panel
        Gsetup                   matlab.ui.container.GridLayout
        LetsPlay                 matlab.ui.control.Button
        YourPlayNum              matlab.ui.control.Spinner
        YouarePlayerLabel        matlab.ui.control.Label
        NumPlayers               matlab.ui.control.Spinner
        NumberofPlayersLabel     matlab.ui.control.Label
        NumCards                 matlab.ui.control.Spinner
        NumberofCardsLabel       matlab.ui.control.Label
        NumCats                  matlab.ui.control.Spinner
        NumberofCategoriesLabel  matlab.ui.control.Label
        clFigure                 matlab.ui.Figure
        GclBoard                 matlab.ui.container.GridLayout
        clBoard                  matlab.ui.container.Panel
        GclLayout                matlab.ui.container.GridLayout
        MovesListPanel           matlab.ui.container.Panel
        Gmoves                   matlab.ui.container.GridLayout
        Guess                    matlab.ui.control.Button
        NextMove                 matlab.ui.control.Button
        Moves                    matlab.ui.control.ListBox
        CardsDistPanel           matlab.ui.container.Panel
        Gcards                   matlab.ui.container.GridLayout
        EnvelopeC3               matlab.ui.control.Spinner
        EnvelopeC2               matlab.ui.control.Spinner
        EnvelopeC1               matlab.ui.control.Spinner
        EnvelopeCsLabel          matlab.ui.control.Label
        CommonC3                 matlab.ui.control.EditField
        CommonC2                 matlab.ui.control.EditField
        CommonC1                 matlab.ui.control.EditField
        CommonCsLabel            matlab.ui.control.Label
        PlayersC3                matlab.ui.control.EditField
        PlayersC2                matlab.ui.control.EditField
        PlayersC1                matlab.ui.control.EditField
        PlayersLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        Mver=0
        mver=1
        prog=22 %χ
        rels=1
        Speaker=[]
        mfilepath=fileparts(mfilename('fullpath'))
        currpath=pwd
        %clmat=[]
        %dldf=[]
        %prstyle=uistyle("BackgroundColor",'g',FontColor='k',FontWeight='bold')
        %pcstyle=uistyle("BackgroundColor",'r',FontColor='w',FontWeight='bold')
        %psstyle=uistyle("Icon","success",IconAlignment='rightmargin')
        %ptstyle=uistyle("FontColor",[0.6 0.6 0.6])
    end

    properties (Dependent, Access = private)
        numP
        numC
        solE
    end

    methods

        function m = get.numP(app)
            m=app.NumPlayers.Value;
        end
        
        function n = get.numC(app)
            n=app.NumCards.Value;
        end

        function s = get.solE(app)
            s=[app.EnvelopeC1.Value app.EnvelopeC2.Value app.EnvelopeC3.Value];
        end

    end
    
    methods (Access = private)
        
        function tryck(app, event, funcall)
            try
                funcall(app, event);
            catch err
                if ~isempty(app.Speaker)
                    SpeakAsync(app.Speaker,'Well, that was unexpected!');
                end
                detail=struct2cell(err.stack)';
                detail=strcat(detail(:,2),' in Line-',cellstr(cellfun(@num2str,detail(:,3),'UniformOutput',0)));
                uialert(app.clFigure,[[err.identifier,': '];err.message;flipud(detail)],'Something Went Wrong! 😱','Icon',"error");
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Setting up the GUI
            h=uiprogressdlg(app.clFigure,'Title','Setting up GUI...','Message','Please wait a moment...','Indeterminate','on');drawnow
            movegui(app.clFigure,'center');
            NumPlayersValueChanged(app,[]);
            %% Loading/Saving startup folder & settings...
            % app.clmat=fullfile(getenv('appdata'),'Clueless','application','cl.mat');
            % if exist(app.clmat,'file')==2
            %     load(app.clmat,'SuF','Opts');
            %     app.currpath=SuF;
            %     if ~isMATLABReleaseOlderThan("R2025a")
            %         app.AppTheme.Enable="on";
            %         if isfield(Opts,'ApThm') && app.AppTheme.State~=Opts.ApThm
            %             app.AppTheme.State=Opts.ApThm;
            %             AppTheme_Callback(app,[]);
            %         else
            %             Opts.ApThm=app.AppTheme.State;
            %             save(app.clmat,'Opts','-append');
            %         end
            %     end
            % else
            %     SuF=app.currpath;
            %     Opts.ApThm=app.AppTheme.State;
            %     try
            %         if exist(fullfile(getenv('appdata'),'Clueless','application'),'dir')~=7
            %             mkdir(fullfile(getenv('appdata'),'Clueless'),'application');
            %         end
            %         save(app.clmat,'SuF','Opts');
            %     catch error
            %         uialert(app.clFigure,error.message,'No Write-Access! 😯','Icon','warning');
            %     end
            % end
            %% Assigning callbacks via try-catch
            % 
            %% Finishing up...
            h.Message='GUI set up.';h.Icon='Success';drawnow
            try
                NET.addAssembly('System.Speech');
                app.Speaker=System.Speech.Synthesis.SpeechSynthesizer;
                SpeakAsync(app.Speaker,'Let''s Play Clueless!');
            catch me
                getReport(me,'extended');
            end
            close(h);
            % focus(app.groupMems);
        end

        % Theme changed function: clFigure
        function clFigureThemeChanged(app, event)
            if app.AppTheme.State==0
                app.clFigure.Theme.BaseColorStyle="dark";
            else
                app.clFigure.Theme.BaseColorStyle="light";
            end
            %app.switchSview.BackgroundColor=fliplightness(app.switchSview.BackgroundColor);
        end

        % Value changed function: NumCards
        function NumCardsValueChanged(app, event)
            app.EnvelopeC1.Limits(2)=app.numC;
            app.EnvelopeC2.Limits(2)=app.numC;
            app.EnvelopeC3.Limits(2)=app.numC;
        end

        % Value changed function: NumPlayers
        function NumPlayersValueChanged(app, event)
            app.YourPlayNum.Limits(2)=app.numP;
            app.Gcards.RowHeight=repelem("1x",app.numP+2);
            app.EnvelopeCsLabel.Layout.Row=app.numP+2;
            app.EnvelopeC1.Layout.Row=app.numP+2;
            app.EnvelopeC2.Layout.Row=app.numP+2;
            app.EnvelopeC3.Layout.Row=app.numP+2;
            app.CommonCsLabel.Layout.Row=app.numP+1;
            app.CommonC1.Layout.Row=app.numP+1;
            app.CommonC2.Layout.Row=app.numP+1;
            app.CommonC3.Layout.Row=app.numP+1;
            if ~isscalar(app.PlayersLabel)
                delete(app.PlayersLabel(2:end));
                delete(app.PlayersC1(2:end));
                delete(app.PlayersC2(2:end));
                delete(app.PlayersC3(2:end));
                app.PlayersLabel(app.numP+1:end)=[];
                app.PlayersC1(app.numP+1:end)=[];
                app.PlayersC2(app.numP+1:end)=[];
                app.PlayersC3(app.numP+1:end)=[];
            end
            for n=2:app.numP
                app.PlayersLabel(n)=copyobj(app.PlayersLabel(1),app.Gcards);
                app.PlayersLabel(n).Layout.Row=n;
                app.PlayersLabel(n).Text=replace(app.PlayersLabel(1).Text,"1",num2str(n));
                app.PlayersC1(n)=copyobj(app.PlayersC1(1),app.Gcards);
                app.PlayersC1(n).Layout.Row=n;
                app.PlayersC1(n).Value=[];
                app.PlayersC2(n)=copyobj(app.PlayersC2(1),app.Gcards);
                app.PlayersC2(n).Layout.Row=n;
                app.PlayersC2(n).Value=[];
                app.PlayersC3(n)=copyobj(app.PlayersC3(1),app.Gcards);
                app.PlayersC3(n).Layout.Row=n;
                app.PlayersC3(n).Value=[];
            end
            YourPlayNumValueChanged(app,[]);
        end

        % Value changed function: YourPlayNum
        function YourPlayNumValueChanged(app, event)
            numY=app.YourPlayNum.Value;
            set(app.PlayersLabel(:),BackgroundColor='none');
            app.PlayersLabel(numY).BackgroundColor=[0.15 0.55 0.87];
        end

        % Button pushed function: LetsPlay
        function LetsPlayButtonPushed(app, event)
            %(De)Activate UI Elements
            app.GameSetupPanel.Enable="off";
            app.CardsDistPanel.Enable="on";
            app.MovesListPanel.Enable="on";
            %Distribute Cards
            set(app.PlayersC1(:),Value="[]");
            set(app.PlayersC2(:),Value="[]");
            set(app.PlayersC3(:),Value="[]");
            set(app.PlayersC1(4),Value="[1,3]");
            set(app.PlayersC2(4),Value="{}");
            set(app.PlayersC3(4),Value="{}");
            set(app.CommonC1,Value="{}");
            set(app.CommonC2,Value="3");
            set(app.CommonC3,Value="{}");
        end

        % Button pushed function: NextMove
        function NextMoveButtonPushed(app, event)
            
        end

        % Button pushed function: Guess
        function GuessButtonPushed(app, event)
            %Check the envelope
            if isequal(app.solE,[4 2 1])
                uialert(app.clFigure,"You Did It!","Congratulations!","Icon","success");
                %If match then Reset
                app.GameSetupPanel.Enable="on";
                app.CardsDistPanel.Enable="off";
                app.MovesListPanel.Enable="off";
                %Reset the UI elements too!
            else
                uialert(app.clFigure,"Try Again...","Sorry!","Icon","info");
                %Else keep moving on
            end
        end

        % Close request function: clFigure
        function clFigureCloseRequest(app, event)
            delete(app)
            % More nuanced closure for later!
            % if ~isempty(app.Speaker)
            %     SpeakAsync(app.Speaker,'Exit, Really?');
            % end
            % BName=uiconfirm(app.clFigure,'Do you want to exit this clueless game?','Exit?','Options',{'YES','NO'},'DefaultOption',2,'CancelOption',2);
            % switch BName
            %     case 'YES'
            %         if ~isempty(app.Speaker)
            %             SpeakAsync(app.Speaker,'Lord Ned is NOT pleased!');
            %         end
            %         if exist(app.clmat,'file')==2
            %             SuF=app.currpath;
            %             save(app.clmat,'SuF','-append');
            %             if app.AppTheme.Enable
            %                 load(app.clmat,'Opts');
            %                 if Opts.ApThm~=app.AppTheme.State
            %                     Opts.ApThm=~Opts.ApThm;
            %                     save(app.clmat,'Opts','-append');
            %                 end
            %             end
            %         end
            %         closereq
            %     case 'NO'
            %         if ~isempty(app.Speaker)
            %             SpeakAsync(app.Speaker,'Lord Ned is pleased!');
            %         end
            % end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.clFigure = uifigure('Visible', 'off');
            app.clFigure.Name = 'Clueless Game';
            app.clFigure.CloseRequestFcn = createCallbackFcn(app, @clFigureCloseRequest, true);
            app.GclBoard = uigridlayout(app.clFigure);
            app.clBoard = uipanel(app.GclBoard);
            app.GclLayout = uigridlayout(app.clBoard);
            app.GameSetupPanel = uipanel(app.GclLayout);
            app.GameSetupPanel.Title = 'Game Setup';
            app.Gsetup = uigridlayout(app.GameSetupPanel);
            app.NumberofCategoriesLabel = uilabel(app.Gsetup);
            app.NumberofCategoriesLabel.Text = 'Number of Categories:';
            app.NumCats = uispinner(app.Gsetup);
            app.NumCats.Limits = [3 5];
            app.NumCats.RoundFractionalValues = 'on';
            app.NumCats.ValueDisplayFormat = '%.0f';
            app.NumCats.Editable = 'off';
            app.NumCats.Enable = 'off';
            app.NumCats.Value = 3;
            app.NumberofCardsLabel = uilabel(app.Gsetup);
            app.NumberofCardsLabel.Text = 'Number of Cards:';
            app.NumCards = uispinner(app.Gsetup);
            app.NumCards.Limits = [4 8];
            app.NumCards.RoundFractionalValues = 'on';
            app.NumCards.ValueDisplayFormat = '%.0f';
            app.NumCards.ValueChangedFcn = createCallbackFcn(app, @NumCardsValueChanged, true);
            app.NumCards.Value = 4;
            app.NumberofPlayersLabel = uilabel(app.Gsetup);
            app.NumberofPlayersLabel.Text = 'Number of Players:';
            app.NumPlayers = uispinner(app.Gsetup);
            app.NumPlayers.Limits = [4 8];
            app.NumPlayers.RoundFractionalValues = 'on';
            app.NumPlayers.ValueDisplayFormat = '%.0f';
            app.NumPlayers.ValueChangedFcn = createCallbackFcn(app, @NumPlayersValueChanged, true);
            app.NumPlayers.Value = 4;
            app.YouarePlayerLabel = uilabel(app.Gsetup);
            app.YouarePlayerLabel.Text = 'You are Player #:';
            app.YourPlayNum = uispinner(app.Gsetup);
            app.YourPlayNum.Limits = [1 8];
            app.YourPlayNum.RoundFractionalValues = 'on';
            app.YourPlayNum.ValueDisplayFormat = '%.0f';
            app.YourPlayNum.ValueChangedFcn = createCallbackFcn(app, @YourPlayNumValueChanged, true);
            app.YourPlayNum.Value = 4;
            app.LetsPlay = uibutton(app.Gsetup, 'push');
            app.LetsPlay.ButtonPushedFcn = createCallbackFcn(app, @LetsPlayButtonPushed, true);
            app.LetsPlay.Text = {'Lord Ned Instructs,'; '"LET''S PLAY!"'};
            app.CardsDistPanel = uipanel(app.GclLayout);
            app.CardsDistPanel.Enable = 'off';
            app.CardsDistPanel.Title = 'Cards Distribution';
            app.Gcards = uigridlayout(app.CardsDistPanel);
            app.PlayersLabel = uilabel(app.Gcards);
            app.PlayersLabel.Text = '👤1';
            app.PlayersC1 = uieditfield(app.Gcards, 'text');
            app.PlayersC1.Editable = 'off';
            app.PlayersC1.Placeholder = 'Start';
            app.PlayersC2 = uieditfield(app.Gcards, 'text');
            app.PlayersC2.Editable = 'off';
            app.PlayersC2.Placeholder = 'the';
            app.PlayersC3 = uieditfield(app.Gcards, 'text');
            app.PlayersC3.Editable = 'off';
            app.PlayersC3.Placeholder = 'game...';
            app.CommonCsLabel = uilabel(app.Gcards);
            app.CommonCsLabel.Text = '🃏';
            app.CommonC1 = uieditfield(app.Gcards, 'text');
            app.CommonC1.Editable = 'off';
            app.CommonC1.Placeholder = 'Start';
            app.CommonC2 = uieditfield(app.Gcards, 'text');
            app.CommonC2.Editable = 'off';
            app.CommonC2.Placeholder = 'the';
            app.CommonC3 = uieditfield(app.Gcards, 'text');
            app.CommonC3.Editable = 'off';
            app.CommonC3.Placeholder = 'game...';
            app.EnvelopeCsLabel = uilabel(app.Gcards);
            app.EnvelopeCsLabel.Text = '✉️';
            app.EnvelopeC1 = uispinner(app.Gcards);
            app.EnvelopeC1.Limits = [0 4];
            app.EnvelopeC1.RoundFractionalValues = 'on';
            app.EnvelopeC1.ValueDisplayFormat = '%.0f';
            app.EnvelopeC2 = uispinner(app.Gcards);
            app.EnvelopeC2.Limits = [0 4];
            app.EnvelopeC2.RoundFractionalValues = 'on';
            app.EnvelopeC2.ValueDisplayFormat = '%.0f';
            app.EnvelopeC3 = uispinner(app.Gcards);
            app.EnvelopeC3.Limits = [0 4];
            app.EnvelopeC3.RoundFractionalValues = 'on';
            app.EnvelopeC3.ValueDisplayFormat = '%.0f';
            app.MovesListPanel = uipanel(app.GclLayout);
            app.MovesListPanel.Enable = 'off';
            app.MovesListPanel.Title = 'Moves List';
            app.Gmoves = uigridlayout(app.MovesListPanel);
            app.Moves = uilistbox(app.Gmoves);
            app.Moves.Items = {};
            app.Moves.Value = {};
            app.NextMove = uibutton(app.Gmoves, 'push');
            app.NextMove.ButtonPushedFcn = createCallbackFcn(app, @NextMoveButtonPushed, true);
            app.NextMove.Text = 'Next Move >';
            app.Guess = uibutton(app.Gmoves, 'push');
            app.Guess.ButtonPushedFcn = createCallbackFcn(app, @GuessButtonPushed, true);
            app.Guess.Text = '< Check Your Solution...';
            % Show the figure after all components are created
            app.clFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Clueless

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.clFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.clFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.clFigure)
        end
    end
end